classdef Andre_Smith_Roadnoisecalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AnyHGVsCheckBox                 matlab.ui.control.CheckBox
        ObstructionCheckBox             matlab.ui.control.CheckBox
        dBLabel                         matlab.ui.control.Label
        AngleofroadobstructedEditField  matlab.ui.control.NumericEditField
        AngleofroadobstructedEditFieldLabel  matlab.ui.control.Label
        MLabel_3                        matlab.ui.control.Label
        HeightofmicrophoneEditField     matlab.ui.control.NumericEditField
        HeightofmicrophoneEditFieldLabel  matlab.ui.control.Label
        MLabel                          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        kmhLabel                        matlab.ui.control.Label
        ResultEditField                 matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        RoadSrufaceTypeDropDown         matlab.ui.control.DropDown
        RoadSrufaceTypeDropDownLabel    matlab.ui.control.Label
        DistancefromsourceEditField     matlab.ui.control.NumericEditField
        DistancefromsourceEditFieldLabel  matlab.ui.control.Label
        PercentageofHGVsEditField       matlab.ui.control.NumericEditField
        PercentageofHGVsEditFieldLabel  matlab.ui.control.Label
        AveragevehiclespeedEditField    matlab.ui.control.NumericEditField
        AveragevehiclespeedEditFieldLabel  matlab.ui.control.Label
        vehicleshourEditField           matlab.ui.control.NumericEditField
        vehicleshourEditFieldLabel      matlab.ui.control.Label
        LA10DropDown                    matlab.ui.control.DropDown
        LA10DropDownLabel               matlab.ui.control.Label
        RoadNoiseCalculatorLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
        
    % Get input values
    flow = app.vehicleshourEditField.Value;
    speed = app.AveragevehiclespeedEditField.Value;
    hgv = app.PercentageofHGVsEditField.Value;
    distance = app.DistancefromsourceEditField.Value;
    
    surface = app.RoadSrufaceTypeDropDown.Value;
    calculationType = app.LA10DropDown.Value;
    micHeight = app.HeightofmicrophoneEditField.Value;
   angularCorrection = app.AngleofroadobstructedEditField.Value;
    %LA10 1 hour  
            
          %LA10 1 hour equation
            if calculationType == "1"
               LA10 = 42.2+10*log10(flow); 

           %L10 18 hour equation 
           elseif calculationType == "2"
               LA10 = 29.1+10*log10(flow * 18);  
            else
                error('Unexpected L10 dropdown value');
            end
 %HGV Speed correction
            HGVSpeedCorrection = 33 * log10(speed + 40 + (500/speed)) + 10 * log10(1 + (5 * hgv) / speed) - 68.8;

%Impervious and pervious surfaces

RoadSurfaceCorrection = 0; %Initialise

           selectedValue = str2double(surface); %Get drop down value to convert to number
           if selectedValue == 1
               RoadSurfaceCorrection = -1; %Impervious
           elseif selectedValue == 2
               RoadSurfaceCorrection = -3.5; %Pervious
           else
               RoadSurfaceCorrection = 0; 
           end
  %Source Distance
 DCalc = sqrt((distance + 3.5)^2 + micHeight^2);
            DistanceCorrection = -10 * log10(DCalc / 13.5);

  %Angular Correction
          
           
            if angularCorrection == 0
                
               angularCorrection = 180;        
            end

            angularCorEq = 10 * log10(angularCorrection / 180);

% Combined Result
Result = LA10 + HGVSpeedCorrection + RoadSurfaceCorrection + angularCorEq - DistanceCorrection;

% Final Result

app.ResultEditField.Value = Result;


        end

        % Value changed function: vehicleshourEditField
        function vehicleshourEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: ObstructionCheckBox
        function ObstructionCheckBoxValueChanged(app, event)
            isChecked2 = app.ObstructionCheckBox.Value;
            if isChecked2 == true
               app.AngleofroadobstructedEditField.Enable = true;
                
            else 
                app.AngleofroadobstructedEditField.Enable = false;
                
                app.AngleofroadobstructedEditField.Value = 180;
            end
            
        end

        % Value changed function: AnyHGVsCheckBox
        function AnyHGVsCheckBoxValueChanged(app, event)
             isChecked3 = app.AnyHGVsCheckBox.Value;
            if isChecked3 == true
               app.PercentageofHGVsEditField.Enable = true;
                
            else 
                app.PercentageofHGVsEditField.Enable = false;
                
                app.PercentageofHGVsEditField.Value = 0;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RoadNoiseCalculatorLabel = uilabel(app.UIFigure);
            app.RoadNoiseCalculatorLabel.Text = 'Road Noise Calculator';
            app.LA10DropDownLabel = uilabel(app.UIFigure);
            app.LA10DropDownLabel.Text = 'LA10';
            app.LA10DropDown = uidropdown(app.UIFigure);
            app.LA10DropDown.Items = {'1 Hour', '18 Hour'};
            app.LA10DropDown.ItemsData = {'1', '2'};
            app.LA10DropDown.Value = '1';
            app.vehicleshourEditFieldLabel = uilabel(app.UIFigure);
            app.vehicleshourEditFieldLabel.Text = 'vehicles/hour';
            app.vehicleshourEditField = uieditfield(app.UIFigure, 'numeric');
            app.vehicleshourEditField.ValueChangedFcn = createCallbackFcn(app, @vehicleshourEditFieldValueChanged, true);
            app.AveragevehiclespeedEditFieldLabel = uilabel(app.UIFigure);
            app.AveragevehiclespeedEditFieldLabel.Text = 'Average vehicle speed';
            app.AveragevehiclespeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.PercentageofHGVsEditFieldLabel = uilabel(app.UIFigure);
            app.PercentageofHGVsEditFieldLabel.Text = 'Percentage of HGVs';
            app.PercentageofHGVsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PercentageofHGVsEditField.Limits = [0 100];
            app.DistancefromsourceEditFieldLabel = uilabel(app.UIFigure);
            app.DistancefromsourceEditFieldLabel.Text = 'Distance from source';
            app.DistancefromsourceEditField = uieditfield(app.UIFigure, 'numeric');
            app.RoadSrufaceTypeDropDownLabel = uilabel(app.UIFigure);
            app.RoadSrufaceTypeDropDownLabel.Text = 'Road Sruface Type';
            app.RoadSrufaceTypeDropDown = uidropdown(app.UIFigure);
            app.RoadSrufaceTypeDropDown.Items = {'Impervious', 'Pervious', 'Unknown'};
            app.RoadSrufaceTypeDropDown.ItemsData = {'1', '2', '3'};
            app.RoadSrufaceTypeDropDown.Value = '1';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResultEditField = uieditfield(app.UIFigure, 'numeric');
            app.kmhLabel = uilabel(app.UIFigure);
            app.kmhLabel.Text = 'km/h';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.MLabel = uilabel(app.UIFigure);
            app.MLabel.Text = 'M';
            app.HeightofmicrophoneEditFieldLabel = uilabel(app.UIFigure);
            app.HeightofmicrophoneEditFieldLabel.Text = 'Height of microphone';
            app.HeightofmicrophoneEditField = uieditfield(app.UIFigure, 'numeric');
            app.MLabel_3 = uilabel(app.UIFigure);
            app.MLabel_3.Text = 'M';
            app.AngleofroadobstructedEditFieldLabel = uilabel(app.UIFigure);
            app.AngleofroadobstructedEditFieldLabel.Text = 'Angle of road obstructed';
            app.AngleofroadobstructedEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleofroadobstructedEditField.Value = 180;
            app.dBLabel = uilabel(app.UIFigure);
            app.dBLabel.Text = 'dB';
            app.ObstructionCheckBox = uicheckbox(app.UIFigure);
            app.ObstructionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ObstructionCheckBoxValueChanged, true);
            app.ObstructionCheckBox.Text = 'Obstruction';
            app.AnyHGVsCheckBox = uicheckbox(app.UIFigure);
            app.AnyHGVsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyHGVsCheckBoxValueChanged, true);
            app.AnyHGVsCheckBox.Text = 'Any HGVs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Andre_Smith_Roadnoisecalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end