classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        HzEditField_6                matlab.ui.control.NumericEditField
        HzEditField_5Label           matlab.ui.control.Label
        HzEditField_5                matlab.ui.control.NumericEditField
        HzEditField_4Label           matlab.ui.control.Label
        HzEditField_4                matlab.ui.control.NumericEditField
        HzEditField_3Label           matlab.ui.control.Label
        HZEditField_3                matlab.ui.control.NumericEditField
        HZEditFieldLabel             matlab.ui.control.Label
        HzEditField_2                matlab.ui.control.NumericEditField
        HzEditField_2Label           matlab.ui.control.Label
        HzEditField                  matlab.ui.control.NumericEditField
        HzEditFieldLabel             matlab.ui.control.Label
        seibalLabel                  matlab.ui.control.Label
        PunchitButton                matlab.ui.control.Button
        RoomMaterialsLabel           matlab.ui.control.Label
        CelingMaterialDropDown       matlab.ui.control.DropDown
        CelingMaterialDropDownLabel  matlab.ui.control.Label
        FloorMaterialDropDown        matlab.ui.control.DropDown
        FloorMaterialDropDownLabel   matlab.ui.control.Label
        WallMaterialDropDown         matlab.ui.control.DropDown
        WallMaterialDropDownLabel    matlab.ui.control.Label
        RoomDimensionsLabel          matlab.ui.control.Label
        HightEditField               matlab.ui.control.NumericEditField
        HightEditFieldLabel          matlab.ui.control.Label
        WidthEditField               matlab.ui.control.NumericEditField
        WidthEditFieldLabel          matlab.ui.control.Label
        LengthEditField              matlab.ui.control.NumericEditField
        LengthEditFieldLabel         matlab.ui.control.Label
        RoomRT60CalculatorLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PunchitButton
        function PunchitButtonPushed(app, event)
            % User input values
            RoomLength = app.lengthEditField.Value;
            RoomWidth = app.WidthEditField.Value;
            RoomHeight = app.HeightEditField.Value;

            %Calculate room volume
            Volume = RoomLength * RoomWidth * RoomHeight;

            %Calculate surface areas
            AreaLWall = RoomLength * RoomHeight;
            AreaRWall = RoomLength * RoomHeight;
            AreaFWall = RoomWidth * RoomHeight;
            AreaBWall = RoomWidth * RoomHeight;
            AreaFloor = RoomLength * RoomWidth;
            AreaCeiling = RoomLength * RoomWidth;

            TotalArea = AreaLWall + AreaRWall + AreaFWall + AreaBWall + AreaFloor +AreaCeiling;

            %Convert DropDown from string (text) to numbers
            MaterialWalls = app.WallMaterialDropDown;
            MaterialFloors = app.FloorMaterialDropDown;
            MaterialCeling = app.CelingMaterialDropDown;

            %Define absorption coefficients
            Carpet = [0.10,0.15, 0.25, 0.30, 0.30];
            PainterPlaster = [0.02, 0.02, 0.02, 0.02, 0.02, 0.02];
            PlasterCeilingTiles = [0.45, 0.70, 0.80, 0.80, 0.65, 0.45];
            Rockwool150mm = [0.15, 0.60, 0.90, 0.90, 0.90, 0.85];

            %turn into a matrix
            Matrix = [Carpet; PainterPlaster; PlasterCeilingTiles; Rockwool150mm];

            %get coefficients based on drop down options
            WallCoefficients = Matrix(MaterialWalls,:);
            FloorCoefficients = Matrix(MaterialFloors:);
            CeilingCoefficients = Matrix(MaterialCeling:);

            % calculcate 
            SabLWall = AreaLWall * WallCoefficients;
            SabRWall = AreaRWall * WallCoefficients;
            SabFWall = AreaFWall * WallCoefficients;
            SabBWall = AreaBWall * WallCoefficients;
            sabFloor = AreaFloor * FloorCoefficients;
            sabCeiling = AreaCeiling * CeilingCoefficients;

            %Total absorption (A)
           TotalAbsorption = SabLWall + SabRWall + SabFWall + SabBWall + sabFloor + sabCeiling;

           %Calculate Sabine equaiton 
           sabine = (0.161 * Volume) ./ TotalAbsorption;

           %Diaplay sabine in GUI
           app.HzEditField = sabine(1);
           app.HzEditField_2 = sabine(2);
           app.HZEditField_3 = sabine(3);
           app.HzEditField_4 = sabine(4);
           app.HzEditField_5 = sabine(5);
           app.HzEditField_6 = sabine(6);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RoomRT60CalculatorLabel = uilabel(app.UIFigure);
            app.RoomRT60CalculatorLabel.Text = 'Room RT60 Calculator';
            app.LengthEditFieldLabel = uilabel(app.UIFigure);
            app.LengthEditFieldLabel.Text = 'Length ';
            app.LengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthEditFieldLabel = uilabel(app.UIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.HightEditFieldLabel = uilabel(app.UIFigure);
            app.HightEditFieldLabel.Text = 'Hight';
            app.HightEditField = uieditfield(app.UIFigure, 'numeric');
            app.RoomDimensionsLabel = uilabel(app.UIFigure);
            app.RoomDimensionsLabel.Text = 'Room Dimensions';
            app.WallMaterialDropDownLabel = uilabel(app.UIFigure);
            app.WallMaterialDropDownLabel.Text = 'Wall Material';
            app.WallMaterialDropDown = uidropdown(app.UIFigure);
            app.WallMaterialDropDown.Items = {'Carpet', 'PainterPlaster', 'PlasterCeilingTiles', 'Rockwool150mm'};
            app.WallMaterialDropDown.ItemsData = [1 2 3 4];
            app.WallMaterialDropDown.Value = 1;
            app.FloorMaterialDropDownLabel = uilabel(app.UIFigure);
            app.FloorMaterialDropDownLabel.Text = 'Floor Material';
            app.FloorMaterialDropDown = uidropdown(app.UIFigure);
            app.FloorMaterialDropDown.Items = {'Carpet', 'PainterPlaster', 'PlasterCeilingTiles', 'Rockwool150mm'};
            app.FloorMaterialDropDown.ItemsData = {'1', '2', '3', '4'};
            app.FloorMaterialDropDown.Value = '1';
            app.CelingMaterialDropDownLabel = uilabel(app.UIFigure);
            app.CelingMaterialDropDownLabel.Text = 'Celing Material';
            app.CelingMaterialDropDown = uidropdown(app.UIFigure);
            app.CelingMaterialDropDown.Items = {'Carpet', 'PainterPlaster', 'PlasterCeilingTiles', 'Rockwool150mm'};
            app.CelingMaterialDropDown.ItemsData = {'1', '2', '3', '4'};
            app.CelingMaterialDropDown.Value = '1';
            app.RoomMaterialsLabel = uilabel(app.UIFigure);
            app.RoomMaterialsLabel.Text = 'Room Materials';
            app.PunchitButton = uibutton(app.UIFigure, 'push');
            app.PunchitButton.ButtonPushedFcn = createCallbackFcn(app, @PunchitButtonPushed, true);
            app.PunchitButton.Text = 'Punch it';
            app.seibalLabel = uilabel(app.UIFigure);
            app.seibalLabel.Text = 'seibal';
            app.HzEditFieldLabel = uilabel(app.UIFigure);
            app.HzEditFieldLabel.Text = '125Hz';
            app.HzEditField = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_2Label = uilabel(app.UIFigure);
            app.HzEditField_2Label.Text = '250Hz';
            app.HzEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.HZEditFieldLabel = uilabel(app.UIFigure);
            app.HZEditFieldLabel.Text = '500HZ';
            app.HZEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_3Label = uilabel(app.UIFigure);
            app.HzEditField_3Label.Text = '1000Hz';
            app.HzEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_4Label = uilabel(app.UIFigure);
            app.HzEditField_4Label.Text = '2000Hz';
            app.HzEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_5Label = uilabel(app.UIFigure);
            app.HzEditField_5Label.Text = '4000Hz';
            app.HzEditField_6 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end