classdef crtn_calc4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        EditField                      matlab.ui.control.NumericEditField
        RoadAngleLabel                 matlab.ui.control.Label
        HeightReciever                 matlab.ui.control.NumericEditField
        TickifHGVsLabel                matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        mLabel_2                       matlab.ui.control.Label
        mLabel                         matlab.ui.control.Label
        kmhLabel                       matlab.ui.control.Label
        Distancefromroad               matlab.ui.control.NumericEditField
        DistancefromroadLabel          matlab.ui.control.Label
        dBCorrectedLabel               matlab.ui.control.Label
        dBLabel                        matlab.ui.control.Label
        RoadGradient                   matlab.ui.control.NumericEditField
        BasicNoiseLevelLabel           matlab.ui.control.Label
        RoadsurfaceLabel               matlab.ui.control.Label
        RoadGradientLabel              matlab.ui.control.Label
        HeightofRecieverLabel          matlab.ui.control.Label
        BasicNoiseLevel                matlab.ui.control.NumericEditField
        SurfaceDropDown                matlab.ui.control.DropDown
        SpeedofVehiclesEditField       matlab.ui.control.NumericEditField
        SpeedofVehiclesEditFieldLabel  matlab.ui.control.Label
        ofHGVsEditField                matlab.ui.control.NumericEditField
        ofHGVsEditFieldLabel           matlab.ui.control.Label
        TotalVehiclesin1hourEditField  matlab.ui.control.NumericEditField
        TotalVehiclesin1hourEditFieldLabel  matlab.ui.control.Label
        LA10DropDown                   matlab.ui.control.DropDown
        LA10DropDownLabel              matlab.ui.control.Label
        Answer                         matlab.ui.control.NumericEditField
        LA101HourButton                matlab.ui.control.Button
        CheckBox                       matlab.ui.control.CheckBox
        CRTNCalculatorLabel            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LA10DropDown
        function LA10DropDownValueChanged(app, event)
      % Get the selected value from the dropdown and convert it to a string
    selectedValue = string(app.LA10DropDown.Value); 
    
    % Display the selected value in the command window for debugging
    disp(['Selected Value: ', selectedValue]); 

    % Update the button text based on the selected dropdown value
    switch selectedValue
        case "1"  % If "1" is selected, set button text to "L10 1 Hour"
            app.LA101HourButton.Text = 'LA10 1 Hour';
        case "2"  % If "2" is selected, set button text to "L10 18 Hour"
            app.LA101HourButton.Text = 'LA10 18 Hour';
    end

        end

        % Value changed function: CheckBox, ofHGVsEditField
        function TickifHGVsCheckBoxValueChanged(app, event)
           ticked = app.CheckBox.Value; %Tickbox for HGVs
            if ticked 
                app.ofHGVsEditField.Enable = true; %Unhides HGVs box
                app.ofHGVsEditFieldLabel.Enable = true;
            else 
                app.ofHGVsEditField.Enable = false; %Hides HGVs box if unticked
                app.ofHGVsEditFieldLabel.Enable = false;
                app.ofHGVsEditField.Value = 0; %Returns to 0 when unticked
            end
        end

        % Button pushed function: LA101HourButton
        function LA101HourButtonPushed(app, event)
            v = app.SpeedofVehiclesEditField.Value;

           %LA10 1 hour equation
            if app.LA10DropDown.Value == "1"
               LA10 = 42.2+10*log10(app.TotalVehiclesin1hourEditField.Value); 

           %L10 18 hour equation 
           elseif app.LA10DropDown.Value == "2"
               LA10 = 29.1+10*log10(app.TotalVehiclesin1hourEditField.Value*18);  
            else
                error('Unexpected L10 dropdown value');
            end


              %Impervious and pervious surfaces
           selectedValue = str2double(app.SurfaceDropDown.Value); %Get drop down value to convert to number
           if selectedValue == 1
               RoadSurfaceCorrection = -3.5; %Impervious
           else
               selectedValue == 2
               RoadSurfaceCorrection = -1; %Pervious
           end


           % %HGV and Speed Correction Equations
           
           %Distance Correction Equations
           d = app.Distancefromroad.Value;
           h = app.HeightReciever.Value;
           
           angulardistance = sqrt(((d+3.5)^2)+(h)^2);
           distancecorrection = -10*log10(angulardistance/13.5);  
          
           HGVsSpeedCorrection = 33*log10(v+40+(500/v))+10*log10(1+((5*app.ofHGVsEditField.Value)/v))-68.8;


           %Basic Noise Level = Original L10 Value as calculated above 
           app.BasicNoiseLevel.Value = LA10;

           %Final Noise Level
           %This ensures that the final noise level doesn't drop below the basic noise level.
           FinalNoiseLevel = LA10 + HGVsSpeedCorrection + distancecorrection + RoadSurfaceCorrection;
           app.Answer.Value = FinalNoiseLevel;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CRTNCalculatorLabel = uilabel(app.GridLayout);
            app.CRTNCalculatorLabel.Text = 'CRTN Calculator';
            app.CheckBox = uicheckbox(app.GridLayout);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @TickifHGVsCheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.LA101HourButton = uibutton(app.GridLayout, 'push');
            app.LA101HourButton.ButtonPushedFcn = createCallbackFcn(app, @LA101HourButtonPushed, true);
            app.LA101HourButton.Text = 'LA10 1 Hour';
            app.Answer = uieditfield(app.GridLayout, 'numeric');
            app.Answer.Editable = 'off';
            app.LA10DropDownLabel = uilabel(app.GridLayout);
            app.LA10DropDownLabel.Text = 'LA10';
            app.LA10DropDown = uidropdown(app.GridLayout);
            app.LA10DropDown.Items = {'1 hour', '18 hours'};
            app.LA10DropDown.ItemsData = {'1', '2'};
            app.LA10DropDown.ValueChangedFcn = createCallbackFcn(app, @LA10DropDownValueChanged, true);
            app.LA10DropDown.Value = '1';
            app.TotalVehiclesin1hourEditFieldLabel = uilabel(app.GridLayout);
            app.TotalVehiclesin1hourEditFieldLabel.Text = 'Total Vehicles in 1 hour';
            app.TotalVehiclesin1hourEditField = uieditfield(app.GridLayout, 'numeric');
            app.ofHGVsEditFieldLabel = uilabel(app.GridLayout);
            app.ofHGVsEditFieldLabel.Enable = 'off';
            app.ofHGVsEditFieldLabel.Text = '% of HGVs';
            app.ofHGVsEditField = uieditfield(app.GridLayout, 'numeric');
            app.ofHGVsEditField.ValueChangedFcn = createCallbackFcn(app, @TickifHGVsCheckBoxValueChanged, true);
            app.ofHGVsEditField.Enable = 'off';
            app.SpeedofVehiclesEditFieldLabel = uilabel(app.GridLayout);
            app.SpeedofVehiclesEditFieldLabel.Text = 'Speed of Vehicles';
            app.SpeedofVehiclesEditField = uieditfield(app.GridLayout, 'numeric');
            app.SurfaceDropDown = uidropdown(app.GridLayout);
            app.SurfaceDropDown.Items = {'Impervious', 'Pervious', 'Unknown'};
            app.SurfaceDropDown.ItemsData = {'1', '2', '3'};
            app.SurfaceDropDown.Value = '1';
            app.BasicNoiseLevel = uieditfield(app.GridLayout, 'numeric');
            app.BasicNoiseLevel.Editable = 'off';
            app.HeightofRecieverLabel = uilabel(app.GridLayout);
            app.HeightofRecieverLabel.Text = 'Height of Reciever';
            app.RoadGradientLabel = uilabel(app.GridLayout);
            app.RoadGradientLabel.Text = 'Road Gradient';
            app.RoadsurfaceLabel = uilabel(app.GridLayout);
            app.RoadsurfaceLabel.Text = 'Road surface';
            app.BasicNoiseLevelLabel = uilabel(app.GridLayout);
            app.BasicNoiseLevelLabel.Text = 'Basic Noise Level ';
            app.RoadGradient = uieditfield(app.GridLayout, 'numeric');
            app.dBLabel = uilabel(app.GridLayout);
            app.dBLabel.Text = 'dB';
            app.dBCorrectedLabel = uilabel(app.GridLayout);
            app.dBCorrectedLabel.Text = 'dB (Corrected)';
            app.DistancefromroadLabel = uilabel(app.GridLayout);
            app.DistancefromroadLabel.Text = 'Distance from road';
            app.Distancefromroad = uieditfield(app.GridLayout, 'numeric');
            app.kmhLabel = uilabel(app.GridLayout);
            app.kmhLabel.Text = 'km/h';
            app.mLabel = uilabel(app.GridLayout);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.GridLayout);
            app.mLabel_2.Text = 'm';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '%';
            app.TickifHGVsLabel = uilabel(app.GridLayout);
            app.TickifHGVsLabel.Text = 'Tick if HGVs';
            app.HeightReciever = uieditfield(app.GridLayout, 'numeric');
            app.RoadAngleLabel = uilabel(app.GridLayout);
            app.RoadAngleLabel.Text = 'Road Angle';
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = crtn_calc4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end